classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI               matlab.ui.Figure
        ExitButton        matlab.ui.control.Button
        removePatientBut  matlab.ui.control.Button
        addPatientBut     matlab.ui.control.Button
        tableOfPatients   matlab.ui.control.Table
        title             matlab.ui.control.Label
    end

    
    properties (Access = private)
        addPatient %function declaration for adding patients
        deletePatient %function declaration for removing patients

    end
    
    properties (Access = public)
    end


    methods (Access = public)
        
        %ADDING PATIENT
        function AddPatientToTable(app, fullName, age, gender, birth, weight, height, illness, physician, appointment,time,logged)
            %calls function AddPatientToTable from the patientAdd

            t = app.tableOfPatients.Data; 
            %current table data
            nr = {fullName, age, gender, birth, weight, height, illness, physician, appointment,time,logged};
            %new row to be added to the table
            app.tableOfPatients.Data = [t;nr];
            %combine t and nr

            display(app.tableOfPatients.Data);
            display(class(app.tableOfPatients.Data));
            %for checking values on the table displayed on the command
            %window
        end

        
        %REMOVING PATIENT

%             app.tableOfPatients.Data = o;
        function deletePatientFunc(app, rn)
            app.tableOfPatients.Data(rn, :) = []; 

           % calls the deletePatient function from the patientDelete app,
           % deletes the selected patient row
        end 
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: addPatientBut
        function addPatientButPushed(app, event)
            app.addPatient = patientAdd(app);
            %as add button is pushed, the patientAdd app would initialize
        end

        % Button pushed function: removePatientBut
        function removePatientButPushed(app, event)
            app.deletePatient = patientDelete(app);
            %as delete button is pushed, the patientDelete app would initialize
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
            %closes the whole application upon clicking exit
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'MATLAB App';
            app.title = uilabel(app.GUI);
            app.title.Text = 'HOSPITAL ELECTRONIC LOGSHEET';
            app.tableOfPatients = uitable(app.GUI);
            app.tableOfPatients.ColumnName = {'Patient Name'; 'Age'; 'Gender'; 'Birthday'; 'Weight (kg)'; 'Height (cm)'; 'Illness'; 'Physician Name'; 'Date of Appointment'; 'Time of Appointment'; 'Date Logged'};
            app.tableOfPatients.RowName = {};
            app.addPatientBut = uibutton(app.GUI, 'push');
            app.addPatientBut.ButtonPushedFcn = createCallbackFcn(app, @addPatientButPushed, true);
            app.addPatientBut.Text = 'Add Patient';
            app.removePatientBut = uibutton(app.GUI, 'push');
            app.removePatientBut.ButtonPushedFcn = createCallbackFcn(app, @removePatientButPushed, true);
            app.removePatientBut.Text = 'Remove Patient';
            app.ExitButton = uibutton(app.GUI, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end