classdef patientAdd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Cancel                       matlab.ui.control.Button
        AppTime                      matlab.ui.control.EditField
        TimeofAppointmentEditFieldLabel  matlab.ui.control.Label
        LogDate                      matlab.ui.control.DatePicker
        LogDateDatePickerLabel       matlab.ui.control.Label
        AppDate                      matlab.ui.control.DatePicker
        DateofAppointmentDatePickerLabel  matlab.ui.control.Label
        PhysicianName                matlab.ui.control.EditField
        PhysicianNameEditFieldLabel  matlab.ui.control.Label
        Illness                      matlab.ui.control.EditField
        IllnessEditFieldLabel        matlab.ui.control.Label
        Height                       matlab.ui.control.NumericEditField
        HeightcmLabel                matlab.ui.control.Label
        Weight                       matlab.ui.control.NumericEditField
        WeightkgLabel                matlab.ui.control.Label
        Age                          matlab.ui.control.NumericEditField
        AgeEditFieldLabel            matlab.ui.control.Label
        DOB                          matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel   matlab.ui.control.Label
        Gender                       matlab.ui.control.EditField
        GenderEditFieldLabel         matlab.ui.control.Label
        middleName                   matlab.ui.control.EditField
        MiddleInitialEditFieldLabel  matlab.ui.control.Label
        firstName                    matlab.ui.control.EditField
        FirstNameEditFieldLabel      matlab.ui.control.Label
        lastName                     matlab.ui.control.EditField
        SurnameEditFieldLabel        matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        ADDPATIENTRECORDLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingapp %callingapp is a property used in the class(whole app)
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.callingapp = main;
            %connects patientAdd app to main app
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        last = app.lastName.Value;
        first = app.firstName.Value;
        middle = app.middleName.Value;

        fullName = num2str(last + ", " + first + " " + middle);
%       concatination 
%       convert value to string
        gender = app.Gender.Value;
        age = app.Age.Value;
        birth = char(app.DOB.Value);
        weight = app.Weight.Value;
        height = app.Height.Value;
        illness = app.Illness.Value;
        physician = app.PhysicianName.Value;
        appointment = char(app.AppDate.Value);
        time = app.AppTime.Value;
        logged = char(app.LogDate.Value);
%       convert value from datetime to char
%       table can only read num, logic, and char

        AddPatientToTable(app.callingapp, fullName, age, gender, birth, weight, height, illness, physician, appointment,time,logged);
%       calls function in main, variables in app to be used in main
        delete(app);
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            delete(app);
            %for cancel button, closes the app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ADDPATIENTRECORDLabel = uilabel(app.UIFigure);
            app.ADDPATIENTRECORDLabel.Text = 'ADD PATIENT RECORD';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SurnameEditFieldLabel = uilabel(app.UIFigure);
            app.SurnameEditFieldLabel.Text = 'Surname';
            app.lastName = uieditfield(app.UIFigure, 'text');
            app.FirstNameEditFieldLabel = uilabel(app.UIFigure);
            app.FirstNameEditFieldLabel.Text = 'First Name';
            app.firstName = uieditfield(app.UIFigure, 'text');
            app.MiddleInitialEditFieldLabel = uilabel(app.UIFigure);
            app.MiddleInitialEditFieldLabel.Text = 'Middle Initial';
            app.middleName = uieditfield(app.UIFigure, 'text');
            app.GenderEditFieldLabel = uilabel(app.UIFigure);
            app.GenderEditFieldLabel.Text = 'Gender';
            app.Gender = uieditfield(app.UIFigure, 'text');
            app.DateofBirthDatePickerLabel = uilabel(app.UIFigure);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DOB = uidatepicker(app.UIFigure);
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.Age = uieditfield(app.UIFigure, 'numeric');
            app.Age.Limits = [0 Inf];
            app.WeightkgLabel = uilabel(app.UIFigure);
            app.WeightkgLabel.Text = 'Weight (kg)';
            app.Weight = uieditfield(app.UIFigure, 'numeric');
            app.Weight.Limits = [0 Inf];
            app.HeightcmLabel = uilabel(app.UIFigure);
            app.HeightcmLabel.Text = 'Height (cm)';
            app.Height = uieditfield(app.UIFigure, 'numeric');
            app.Height.Limits = [0 Inf];
            app.IllnessEditFieldLabel = uilabel(app.UIFigure);
            app.IllnessEditFieldLabel.Text = 'Illness';
            app.Illness = uieditfield(app.UIFigure, 'text');
            app.PhysicianNameEditFieldLabel = uilabel(app.UIFigure);
            app.PhysicianNameEditFieldLabel.Text = 'Physician Name';
            app.PhysicianName = uieditfield(app.UIFigure, 'text');
            app.DateofAppointmentDatePickerLabel = uilabel(app.UIFigure);
            app.DateofAppointmentDatePickerLabel.Text = 'Date of Appointment';
            app.AppDate = uidatepicker(app.UIFigure);
            app.LogDateDatePickerLabel = uilabel(app.UIFigure);
            app.LogDateDatePickerLabel.Text = 'Log Date';
            app.LogDate = uidatepicker(app.UIFigure);
            app.TimeofAppointmentEditFieldLabel = uilabel(app.UIFigure);
            app.TimeofAppointmentEditFieldLabel.Text = 'Time of Appointment';
            app.AppTime = uieditfield(app.UIFigure, 'text');
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patientAdd(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end